classdef LOGIN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Id                          matlab.ui.control.Label
        Passwor                     matlab.ui.control.Label
        User                        matlab.ui.control.Label
        Image3                      matlab.ui.control.Image
        SpecialistIDEditField       matlab.ui.control.EditField
        SpecialistIDEditFieldLabel  matlab.ui.control.Label
        PasswordEditField           matlab.ui.control.EditField
        PasswordEditFieldLabel      matlab.ui.control.Label
        UsernameEditField           matlab.ui.control.EditField
        UsernameEditFieldLabel      matlab.ui.control.Label
        Image5                      matlab.ui.control.Image
        LoginButton                 matlab.ui.control.Button
        LOGINLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.User.Tooltip = 'It must be a unique username.';
           app.Passwor.Tooltip = 'The password must be at least 4 characters long.';
           app.Id.Tooltip = 'The specialist ID must be at least 6 characters long.';
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            Username = string(app.UsernameEditField.Value);
            Password = string(app.PasswordEditField.Value);
            SpecialistID = string(app.SpecialistIDEditField.Value);
        
            filename = 'Registration_data.xlsx';
            T = readtable(filename, 'Sheet', 1, 'Range', 'D:F');
        
            length = size(T);
            k = 1;
        
            for i = 1:length
                if strcmp(Username, T.Username{i}) && strcmp(Password, T.Password{i}) && strcmp(SpecialistID, T.SpecialistID{i})
                    k = 2;
                    break;
                end
            end
        
            if k == 2
                uialert(app.UIFigure, "Login successful", "Success", "Icon", "success");
                pause(1);
                PATIENT
                delete(app);
            else
                uialert(app.UIFigure, "Incorrect credentials", "Error", "Icon", "warning");
            end
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            MAINSCREEN
            delete(app)
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            SETTINGS
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LOGINLabel = uilabel(app.UIFigure);
            app.LOGINLabel.Text = 'LOGIN';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.SpecialistIDEditFieldLabel = uilabel(app.UIFigure);
            app.SpecialistIDEditFieldLabel.Text = 'Specialist ID';
            app.SpecialistIDEditField = uieditfield(app.UIFigure, 'text');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.User = uilabel(app.UIFigure);
            app.User.Text = '*';
            app.Passwor = uilabel(app.UIFigure);
            app.Passwor.Text = '*';
            app.Id = uilabel(app.UIFigure);
            app.Id.Text = '*';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LOGIN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end