classdef PATIENT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RegisterpatientTab              matlab.ui.container.Tab
        Fech                            matlab.ui.control.Label
        Paciente                        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        SaveButton                      matlab.ui.control.Button
        DateofdiagnosisDatePicker       matlab.ui.control.DatePicker
        DateofdiagnosisDatePickerLabel  matlab.ui.control.Label
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        ENTERPATIENTDATALabel           matlab.ui.control.Label
        DiagnoseTab                     matlab.ui.container.Tab
        LamparaDensidad                 matlab.ui.control.Lamp
        Image5_2                        matlab.ui.control.Image
        Image3_2                        matlab.ui.control.Image
        Label4                          matlab.ui.control.Label
        PatientIDLabel                  matlab.ui.control.Label
        DiagnoseButton                  matlab.ui.control.Button
        Densidad                        matlab.ui.control.Label
        Tipo                            matlab.ui.control.Label
        Diagnostico                     matlab.ui.control.Label
        ParasiticdensityLabel           matlab.ui.control.Label
        TypeofmalariaLabel              matlab.ui.control.Label
        DiagnosisofmalariaLabel         matlab.ui.control.Label
        RESULTSLabel                    matlab.ui.control.Label
        DIAGNOSISLabel                  matlab.ui.control.Label
        DiagnosticarButton              matlab.ui.control.Button
        UploadimageButton               matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        HistoryTab                      matlab.ui.container.Tab
        Image9                          matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image5_3                        matlab.ui.control.Image
        Image3_3                        matlab.ui.control.Image
        ReloadhistoryButton             matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        IDdelpaciente
        img 
        DatosHistorial
        rutaImagen
        Fecha
    end
    
    methods (Access = private)

        function resetRegistro(app)
        app.PatientIDEditField.Value = '';
        app.DateofdiagnosisDatePicker.Value = datetime('');
        app.IDdelpaciente = '';
        end

        function resetDiagnostico(app)
            app.Diagnostico.Text = '';
            app.Tipo.Text = '';
            app.Densidad.Text = '';
            app.Label4.Text = '';
        
            app.Diagnostico.Visible = 'off';
            app.Tipo.Visible = 'off';
            app.Densidad.Visible = 'off';
            app.LamparaDensidad.Visible = 'off';

            cla(app.UIAxes);
            cla(app.UIAxes2);
        
            app.img = [];
            app.rutaImagen = '';
         end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.Diagnostico.Visible = 'off';
          app.Tipo.Visible = 'off';
          app.Densidad.Visible = 'off';
          app.LamparaDensidad.Visible = 'off';
          app.Paciente.Tooltip = 'The patient identifier must start with "PA" followeb by 4 numbers.';
          app.Fech.Tooltip = 'Enter the date of which the diagnosis will be performed.';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.IDdelpaciente = upper(strtrim(string(app.PatientIDEditField.Value)));
            app.Fecha = app.DateofdiagnosisDatePicker.Value;
            % Validate empty fields
            if app.IDdelpaciente == "" || isnat(app.Fecha)
                uialert(app.UIFigure, "Please fill in both the patient ID and the diagnosis date.", ...
                    "Imcomplete Fields", "Icon", "warning");
                return;
            end
        
            % Validate ID format
            if isempty(regexp(app.IDdelpaciente, "^PA\d{4}$", "once"))
                uialert(app.UIFigure, "The patient ID must start with 'PA' followed by 4 digits. Example: PA1234", ...
                    "Invalid ID Format", "Icon", "warning");
                return;
            end

            % Create a row with the correct columns
            nuevaFila = table(app.IDdelpaciente, app.Fecha, "", "", ...
                'VariableNames', {'IDdelpaciente', 'Fecha', 'EsPrimerRegistro', 'Resultados'});
        
            filename = 'Patient_data.xlsx';
        
            if exist(filename, 'file') == 0
                writetable(nuevaFila, filename);
            else
                datos = readtable(filename);
        
                % Add missing columns if necessary
                columnas = {'IDdelpaciente', 'Fecha', 'EsPrimerRegistro', 'Resultados'};
                for i = 1:length(columnas)
                    if ~ismember(columnas{i}, datos.Properties.VariableNames)
                        datos.(columnas{i}) = repmat("", height(datos), 1);
                    end
                end
        
                % Check if record already exists
                existe = strcmp(datos.IDdelpaciente, app.IDdelpaciente) & datos.Fecha == app.Fecha;
                if any(existe)
                    uialert(app.UIFigure,"This patient has already been registered on this date. Please try again.", ...
                        "Patient Already Registered", "Icon", "warning");
                    return;
                end
        
                % Add the new record
                datosActualizados = [datos; nuevaFila];
                writetable(datosActualizados, filename);
            end
        
            % Confirmation
            app.Label4.Text = app.IDdelpaciente;
            uialert(app.UIFigure, ...
                "Patient successfully registered. You may now proceed with the diagnosis.", ...
                "Successful Registration", "Icon", "success");
        end

        % Button pushed function: ReloadhistoryButton
        function ReloadhistoryButtonPushed(app, event)
            datos = readtable('Patient_data.xlsx');
        
            id_correctos = ~cellfun(@isempty, regexp(datos.IDdelpaciente, "^PA\d{4}$", "once"));
            fecha_correcta = ~isnat(datetime(datos.Fecha, 'InputFormat', 'dd-MMM-yyyy', 'Format', 'dd-MMM-yyyy'));
            

            datos_validos = datos(id_correctos & fecha_correcta, :);
            datos_validos = sortrows(datos_validos, {'IDdelpaciente', 'Fecha'});
        
            es_primer = strings(height(datos_validos), 1);
            [~, first_idx] = unique(datos_validos.IDdelpaciente, 'first');
            es_primer(first_idx) = "👤 First register";
            datos_validos.EsPrimerRegistro = es_primer;
        
            columnas_orden = {'IDdelpaciente', 'Fecha', 'EsPrimerRegistro', 'Resultados'};
            columnas_presentes = intersect(columnas_orden, datos_validos.Properties.VariableNames, 'stable');
            datos_mostrar = datos_validos(:, columnas_presentes);
        
            app.DatosHistorial = datos_mostrar;
            app.UITable.Data = datos_mostrar;           
        end

        % Button pushed function: UploadimageButton
        function UploadimageButtonPushed(app, event)
             [archivo, carpeta] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.tiff', 'Imágenes (.png,.jpg,.jpeg,*.tif)'}, ...
             'Selecciona una imagen');

             if isequal(archivo, 0)
                return
             end

            app.rutaImagen = fullfile(carpeta, archivo);
            app.img = imread(app.rutaImagen);
            imshow(app.img, 'Parent',app.UIAxes);
        end

        % Button pushed function: DiagnoseButton
        function DiagnoseButtonPushed(app, event)
            if isempty(app.img)
               uialert(app.UIFigure, 'First upload an image', 'Aviso');
               return;
            end 
            [diagnostico, tipo, densidad, imagen_segmentada] = diagnostico_malaria(app.rutaImagen);


            imshow(imagen_segmentada, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Segmentation with parasites and white blood cells detected');
            axis(app.UIAxes2, 'image');  
        
            app.Diagnostico.Text = char(diagnostico);
            app.Tipo.Text = char(tipo);
            if ischar(densidad)
                app.Densidad.Text = densidad;
            else
                app.Densidad.Text = sprintf('%.2f parasites/µL', densidad);
            end

            app.Diagnostico.Visible = 'on';
            app.Tipo.Visible = 'on';
            app.Densidad.Visible = 'on';
            app.LamparaDensidad.Visible = 'on';

            if strcmp(densidad, "sano")
                app.LamparaDensidad.Color = [0.5 0.5 0.5]; % Gris 
            elseif strcmp(densidad, "Baja")
                app.LamparaDensidad.Color = [0 1 0];       % Verde
            elseif strcmp(densidad, "Moderada")
                app.LamparaDensidad.Color = [1 1 0];       % Amarillo
            else
                app.LamparaDensidad.Color = [1 0 0];       % Rojo
            end

           filename = 'Patient_data.xlsx';
           idPaciente = app.Label4.Text;
           resultado = string(tipo);
            
           if isfile(filename)
                datos = readtable(filename, 'TextType', 'string'); 
                   if ~isstring(datos.Resultados)
                        datos.Resultados = string(datos.Resultados);
                   end
                   disp(resultado)
                fila = find(strcmp(datos.IDdelpaciente, idPaciente) & datos.Fecha == app.Fecha);
                datos.Resultados(fila) = resultado;
                writetable(datos, filename);
           end
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            AJUSTES
            delete(app)
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            datos_ordenados = sortrows(app.DatosHistorial, 'IDdelpaciente');
            app.UITable.Data = datos_ordenados;
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            datos_ordenados = sortrows(app.DatosHistorial, 'Fecha', 'ascend');
            app.UITable.Data = datos_ordenados;
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
           datos_ordenados = sortrows(app.DatosHistorial, 'Fecha', 'descend');
           app.UITable.Data = datos_ordenados;
        end

        % Image clicked function: Image9
        function Image9Clicked(app, event)
           datos_ordenados = sortrows(app.DatosHistorial, 'IDdelpaciente', 'descend');
           app.UITable.Data = datos_ordenados;
        end

        % Image clicked function: Image3_3
        function Image3_3Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Image clicked function: Image5_3
        function Image5_3Clicked(app, event)
            AJUSTES
            delete(app)
        end

        % Image clicked function: Image3_2
        function Image3_2Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Image clicked function: Image5_2
        function Image5_2Clicked(app, event)
            AJUSTES
            delete(app)
        end

        % Callback function
        function Image10Clicked(app, event)
           datos_ordenados = sortrows(app.DatosHistorial, '👤 Primer registro', 'ascend');
           app.UITable.Data = datos_ordenados;
        end

        % Callback function
        function Image11Clicked(app, event)
           datos_ordenados = sortrows(app.DatosHistorial, '👤 Primer registro', 'descend');
           app.UITable.Data = datos_ordenados;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = event.NewValue;

            if selectedTab ~= app.RegisterpatientTab
                resetRegistro(app);
            end

            if selectedTab ~= app.DiagnoseTab;
                resetDiagnostico(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RegisterpatientTab = uitab(app.TabGroup);
            app.RegisterpatientTab.Title = 'Register patient';
            app.ENTERPATIENTDATALabel = uilabel(app.RegisterpatientTab);
            app.ENTERPATIENTDATALabel.Text = 'ENTER PATIENT DATA';
            app.PatientIDEditFieldLabel = uilabel(app.RegisterpatientTab);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.RegisterpatientTab, 'text');
            app.DateofdiagnosisDatePickerLabel = uilabel(app.RegisterpatientTab);
            app.DateofdiagnosisDatePickerLabel.Text = 'Date of diagnosis';
            app.DateofdiagnosisDatePicker = uidatepicker(app.RegisterpatientTab);
            app.SaveButton = uibutton(app.RegisterpatientTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Image5 = uiimage(app.RegisterpatientTab);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image3 = uiimage(app.RegisterpatientTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.Paciente = uilabel(app.RegisterpatientTab);
            app.Paciente.Text = '*';
            app.Fech = uilabel(app.RegisterpatientTab);
            app.Fech.Text = '*';
            app.DiagnoseTab = uitab(app.TabGroup);
            app.DiagnoseTab.Title = 'Diagnose';
            app.UIAxes = uiaxes(app.DiagnoseTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.DiagnoseTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UploadimageButton = uibutton(app.DiagnoseTab, 'push');
            app.UploadimageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimageButtonPushed, true);
            app.UploadimageButton.Text = 'Upload image';
            app.DiagnosticarButton = uibutton(app.DiagnoseTab, 'push');
            app.DiagnosticarButton.Text = 'Diagnosticar';
            app.DIAGNOSISLabel = uilabel(app.DiagnoseTab);
            app.DIAGNOSISLabel.Text = 'DIAGNOSIS';
            app.RESULTSLabel = uilabel(app.DiagnoseTab);
            app.RESULTSLabel.Text = 'RESULTS';
            app.DiagnosisofmalariaLabel = uilabel(app.DiagnoseTab);
            app.DiagnosisofmalariaLabel.Text = 'Diagnosis of malaria:';
            app.TypeofmalariaLabel = uilabel(app.DiagnoseTab);
            app.TypeofmalariaLabel.Text = 'Type of malaria:';
            app.ParasiticdensityLabel = uilabel(app.DiagnoseTab);
            app.ParasiticdensityLabel.Text = 'Parasitic density:';
            app.Diagnostico = uilabel(app.DiagnoseTab);
            app.Tipo = uilabel(app.DiagnoseTab);
            app.Tipo.Text = 'Label2';
            app.Densidad = uilabel(app.DiagnoseTab);
            app.Densidad.Text = 'Label3';
            app.DiagnoseButton = uibutton(app.DiagnoseTab, 'push');
            app.DiagnoseButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseButtonPushed, true);
            app.DiagnoseButton.Text = 'Diagnose';
            app.PatientIDLabel = uilabel(app.DiagnoseTab);
            app.PatientIDLabel.Text = 'Patient ID: ';
            app.Label4 = uilabel(app.DiagnoseTab);
            app.Label4.Text = 'Label4';
            app.Image3_2 = uiimage(app.DiagnoseTab);
            app.Image3_2.ImageClickedFcn = createCallbackFcn(app, @Image3_2Clicked, true);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.Image5_2 = uiimage(app.DiagnoseTab);
            app.Image5_2.ImageClickedFcn = createCallbackFcn(app, @Image5_2Clicked, true);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.LamparaDensidad = uilamp(app.DiagnoseTab);
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.UITable = uitable(app.HistoryTab);
            app.UITable.ColumnName = {'Patient ID'; 'Date of diagnosis'; 'First register'; 'Results'};
            app.UITable.RowName = {};
            app.ReloadhistoryButton = uibutton(app.HistoryTab, 'push');
            app.ReloadhistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadhistoryButtonPushed, true);
            app.ReloadhistoryButton.Text = 'Reload history';
            app.Image3_3 = uiimage(app.HistoryTab);
            app.Image3_3.ImageClickedFcn = createCallbackFcn(app, @Image3_3Clicked, true);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.Image5_3 = uiimage(app.HistoryTab);
            app.Image5_3.ImageClickedFcn = createCallbackFcn(app, @Image5_3Clicked, true);
            app.Image5_3.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image6 = uiimage(app.HistoryTab);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'arriba.png');
            app.Image7 = uiimage(app.HistoryTab);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'arriba.png');
            app.Image8 = uiimage(app.HistoryTab);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'abajo.png');
            app.Image9 = uiimage(app.HistoryTab);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Image9Clicked, true);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'abajo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PATIENT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end