classdef SETTINGS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ContactoLabel          matlab.ui.control.Label
        LogoutButton           matlab.ui.control.Button
        ChangepasswordButton   matlab.ui.control.Button
        Image3                 matlab.ui.control.Image
        LanguageDropDown       matlab.ui.control.DropDown
        LanguageDropDownLabel  matlab.ui.control.Label
        Image5                 matlab.ui.control.Image
        SETTINGSLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            texto = ['Need assistance or in case of emergency?\n\n' ...
                 'Please contact the manufacturer: Higia\n' ...
                 'Phone: +34 900 844 083\n' ...
                 'Email: info@higi.ia.com']; 
        app.ContactoLabel.Text = sprintf(texto);
        app.ContactoLabel.FontWeight = 'bold';
        app.ContactoLabel.FontSize = 10;
        app.ContactoLabel.HorizontalAlignment = 'center';
        end

        % Value changed function: LanguageDropDown
        function LanguageDropDownValueChanged(app, event)
            value = app.LanguageDropDown.Value;
        switch value
        case 'English'
        
        case 'Español'
            delete(app)
            nuevaApp = LoginApp(LUMADIAGNOSTICS); 
        end
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            MAINSCREEN
            delete(app)
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            MAINSCREEN
            delete(app)
        end

        % Button pushed function: ChangepasswordButton
        function ChangepasswordButtonPushed(app, event)
            CHANGEPASSWORD
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SETTINGSLabel = uilabel(app.UIFigure);
            app.SETTINGSLabel.Text = 'SETTINGS';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.LanguageDropDownLabel = uilabel(app.UIFigure);
            app.LanguageDropDownLabel.Text = 'Language';
            app.LanguageDropDown = uidropdown(app.UIFigure);
            app.LanguageDropDown.Items = {'Español', 'English'};
            app.LanguageDropDown.ValueChangedFcn = createCallbackFcn(app, @LanguageDropDownValueChanged, true);
            app.LanguageDropDown.Value = 'English';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.ChangepasswordButton = uibutton(app.UIFigure, 'push');
            app.ChangepasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ChangepasswordButtonPushed, true);
            app.ChangepasswordButton.Text = 'Change password';
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Log out';
            app.ContactoLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SETTINGS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end