classdef AJUSTES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CambiarcontraseaButton  matlab.ui.control.Button
        CerrarsesinButton       matlab.ui.control.Button
        IdiomaDropDown          matlab.ui.control.DropDown
        IdiomaDropDownLabel     matlab.ui.control.Label
        Image3                  matlab.ui.control.Image
        Image5                  matlab.ui.control.Image
        Image6                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        AJUSTESLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            ETIQUETA
            delete(app)
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            INSTRUCCIONES
            delete(app)
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Drop down opening function: IdiomaDropDown
        function IdiomaDropDownOpening(app, event)
       
        end

        % Value changed function: IdiomaDropDown
        function IdiomaDropDownValueChanged(app, event)
            value = app.IdiomaDropDown.Value;
        switch value
        case 'Español'
        
        case 'English'
            delete(app)
            nuevaApp = LoginApp(MAINSCREEN); 
        end
        end

        % Button pushed function: CerrarsesinButton
        function CerrarsesinButtonPushed(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Button pushed function: CambiarcontraseaButton
        function CambiarcontraseaButtonPushed(app, event)
           CAMBIARCONTRASENA
           delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AJUSTESLabel = uilabel(app.UIFigure);
            app.AJUSTESLabel.Text = 'AJUSTES';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'instrucciones2.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Etiqueta.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.IdiomaDropDownLabel = uilabel(app.UIFigure);
            app.IdiomaDropDownLabel.Text = 'Idioma';
            app.IdiomaDropDown = uidropdown(app.UIFigure);
            app.IdiomaDropDown.Items = {'Español', 'English'};
            app.IdiomaDropDown.DropDownOpeningFcn = createCallbackFcn(app, @IdiomaDropDownOpening, true);
            app.IdiomaDropDown.ValueChangedFcn = createCallbackFcn(app, @IdiomaDropDownValueChanged, true);
            app.IdiomaDropDown.Value = 'Español';
            app.CerrarsesinButton = uibutton(app.UIFigure, 'push');
            app.CerrarsesinButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarsesinButtonPushed, true);
            app.CerrarsesinButton.Text = 'Cerrar sesión';
            app.CambiarcontraseaButton = uibutton(app.UIFigure, 'push');
            app.CambiarcontraseaButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarcontraseaButtonPushed, true);
            app.CambiarcontraseaButton.Text = 'Cambiar contraseña';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AJUSTES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end