classdef PACIENTE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DatosTab                      matlab.ui.container.Tab
        GuardarButton                 matlab.ui.control.Button
        FechadediagnsticoDatePicker   matlab.ui.control.DatePicker
        FechadediagnsticoDatePickerLabel  matlab.ui.control.Label
        IDdelpacienteEditField        matlab.ui.control.EditField
        IDdelpacienteEditFieldLabel   matlab.ui.control.Label
        INGRESEDATOSDELPACIENTELabel  matlab.ui.control.Label
        DiagnsticoTab                 matlab.ui.container.Tab
        Label4                        matlab.ui.control.Label
        IDdelpacienteLabel            matlab.ui.control.Label
        DiagnosticarButton_2          matlab.ui.control.Button
        Label3                        matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        DensidadparasitariaLabel      matlab.ui.control.Label
        TipodemalariaLabel            matlab.ui.control.Label
        DiagnsticodelamalariaLabel    matlab.ui.control.Label
        RESULTADOSLabel               matlab.ui.control.Label
        DIAGNSTICOLabel               matlab.ui.control.Label
        DiagnosticarButton            matlab.ui.control.Button
        CargarimagenButton_2          matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        HistorialTab                  matlab.ui.container.Tab
        RecargarhistorialButton       matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        IDdelpaciente = "" 
        img 
    end
    
    methods (Access = private)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            app.IDdelpaciente = upper(strtrim(string(app.IDdelpacienteEditField.Value)));  
            Fecha = app.FechadediagnsticoDatePicker.Value;
        
            if app.IDdelpaciente == "" || isnat(Fecha)
                uialert(app.UIFigure, ...
                    "Por favor, rellena tanto el ID del paciente como la fecha de diagnóstico.", ...
                    "Campos incompletos", "Icon", "warning");
                return;
            end

            if isempty(regexp(app.IDdelpaciente, "^PA\d{4}$", "once"))
                uialert(app.UIFigure, ...
                    "El ID del paciente debe comenzar con 'PA' seguido de 4 números. Ejemplo: PA1234", ...
                    "Formato de ID inválido", "Icon", "warning");
                return;
            end
        
            T = table(app.IDdelpaciente, Fecha);
            filename = 'Datos_paciente.xlsx';
        
            if exist(filename, 'file') == 0
                writetable(T, filename, 'Sheet', 1, 'Range', 'A1');
            else
                datos = readtable(filename);
                Duplicado = any(strcmp(datos.IDdelpaciente, app.IDdelpaciente) & datos.Fecha == Fecha);
                if Duplicado
                    uialert(app.UIFigure, ...
                        "Por favor, este paciente ya ha sido registrado en esta fecha. Inténtalo de nuevo.", ...
                        "Paciente ya registrado", "Icon", "warning");
                    return;
                end
                writetable(T, filename, 'Sheet', 1, 'WriteMode', 'append');
            end
        

            app.Label4.Text = app.IDdelpaciente;
            uialert(app.UIFigure, ...
                "Paciente registrado correctamente. Por favor, carga la imagen.", "Registro", "Icon", "success");
        end

        % Button pushed function: RecargarhistorialButton
        function RecargarhistorialButtonPushed(app, event)
             filename = 'Datos_paciente.xlsx';
            
                if exist(filename, 'file') == 0
                    app.UITable.Data = {};
                    return;
                end

                datos = readtable(filename);
                app.UITable.Data = datos;
        end

        % Button pushed function: CargarimagenButton_2
        function CargarimagenButton_2Pushed(app, event)
             [archivo, carpeta] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.tiff', 'Imágenes (.png,.jpg,.jpeg,*.tif)'}, ...
             'Selecciona una imagen');

             if isequal(archivo, 0)
                return
             end

            rutaImagen = fullfile(carpeta, archivo);
            app.img = imread(rutaImagen);
            imshow(app.img, 'Parent',app.UIAxes);
        end

        % Button pushed function: DiagnosticarButton_2
        function DiagnosticarButton_2Pushed(app, event)
            if isempty(app.img)
               uialert(app.UIFigure, 'Primero cargue una imagen', 'Aviso');
               return;
            end
            Iotsu = otsu(app.img);
            imshow(Iotsu, 'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.INGRESEDATOSDELPACIENTELabel = uilabel(app.DatosTab);
            app.INGRESEDATOSDELPACIENTELabel.Text = 'INGRESE DATOS DEL PACIENTE';
            app.IDdelpacienteEditFieldLabel = uilabel(app.DatosTab);
            app.IDdelpacienteEditFieldLabel.Text = 'ID del paciente';
            app.IDdelpacienteEditField = uieditfield(app.DatosTab, 'text');
            app.FechadediagnsticoDatePickerLabel = uilabel(app.DatosTab);
            app.FechadediagnsticoDatePickerLabel.Text = 'Fecha de diagnóstico';
            app.FechadediagnsticoDatePicker = uidatepicker(app.DatosTab);
            app.GuardarButton = uibutton(app.DatosTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.DiagnsticoTab = uitab(app.TabGroup);
            app.DiagnsticoTab.Title = 'Diagnóstico';
            app.UIAxes = uiaxes(app.DiagnsticoTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.DiagnsticoTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.CargarimagenButton_2 = uibutton(app.DiagnsticoTab, 'push');
            app.CargarimagenButton_2.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButton_2Pushed, true);
            app.CargarimagenButton_2.Text = 'Cargar imagen';
            app.DiagnosticarButton = uibutton(app.DiagnsticoTab, 'push');
            app.DiagnosticarButton.Text = 'Diagnosticar';
            app.DIAGNSTICOLabel = uilabel(app.DiagnsticoTab);
            app.DIAGNSTICOLabel.Text = 'DIAGNÓSTICO';
            app.RESULTADOSLabel = uilabel(app.DiagnsticoTab);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.DiagnsticodelamalariaLabel = uilabel(app.DiagnsticoTab);
            app.DiagnsticodelamalariaLabel.Text = 'Diagnóstico de la malaria:';
            app.TipodemalariaLabel = uilabel(app.DiagnsticoTab);
            app.TipodemalariaLabel.Text = 'Tipo de malaria: ';
            app.DensidadparasitariaLabel = uilabel(app.DiagnsticoTab);
            app.DensidadparasitariaLabel.Text = 'Densidad parasitaria:';
            app.Label = uilabel(app.DiagnsticoTab);
            app.Label2 = uilabel(app.DiagnsticoTab);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.DiagnsticoTab);
            app.Label3.Text = 'Label3';
            app.DiagnosticarButton_2 = uibutton(app.DiagnsticoTab, 'push');
            app.DiagnosticarButton_2.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticarButton_2Pushed, true);
            app.DiagnosticarButton_2.Text = 'Diagnosticar';
            app.IDdelpacienteLabel = uilabel(app.DiagnsticoTab);
            app.IDdelpacienteLabel.Text = 'ID del paciente: ';
            app.Label4 = uilabel(app.DiagnsticoTab);
            app.Label4.Text = 'Label4';
            app.HistorialTab = uitab(app.TabGroup);
            app.HistorialTab.Title = 'Historial';
            app.UITable = uitable(app.HistorialTab);
            app.UITable.ColumnName = {'ID del paciente'; 'Fecha de diagnóstico'};
            app.UITable.RowName = {};
            app.RecargarhistorialButton = uibutton(app.HistorialTab, 'push');
            app.RecargarhistorialButton.ButtonPushedFcn = createCallbackFcn(app, @RecargarhistorialButtonPushed, true);
            app.RecargarhistorialButton.Text = 'Recargar historial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PACIENTE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end