classdef PATIENTDATA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image3                   matlab.ui.control.Image
        Image5                   matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        DateDatePicker           matlab.ui.control.DatePicker
        DateDatePickerLabel      matlab.ui.control.Label
        PatientIDEditField       matlab.ui.control.EditField
        PatientIDEditFieldLabel  matlab.ui.control.Label
        Image6                   matlab.ui.control.Image
        UploadtheimageButton     matlab.ui.control.Button
        DiagnoseButton           matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        ENTERPATIENTDATALabel    matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            PatientID = string(app.PatientIDEditField.Value);
            Date = string(app.DateDatePicker.Value);
            
            T = table(PatientID, Date);
            filename = 'Patient_data.xlsx';
            
            if exist(filename, 'file') == 0
                writetable(T, filename, 'Sheet', 1, 'Range', 'A1');
            else
                data = readtable(filename);
            
                Duplicate = any(strcmp(data.PatientID, PatientID) & strcmp(data.Date, Date));
            
                if Duplicate
                    uialert(app.UIFigure, ...
                        "This patient has already been registered on this date. Please try again.", ...
                        "Patient Already Registered", "Icon", "warning");
                    return
                end
            
                writetable(T, filename, 'Sheet', 1, 'WriteMode', 'append');
            end
            
            uialert(app.UIFigure, "Patient successfully registered. Please upload the image.", "Registration", "Icon", "success");
            pause(1);
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            MAINSCREEN
            delete(app)
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            LABEL
            delete(app)
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            INSTRUCTIONS
            delete(app)
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            SETTINGS
            delete(app)
        end

        % Button pushed function: UploadtheimageButton
        function UploadtheimageButtonPushed(app, event)
            [file, folder] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.tiff', 'Images (.png, .jpg, .jpeg, .tif)'}, ...
             'Select an image');

            if isequal(file, 0)
                return
            end
            
            imagePath = fullfile(folder, file);
            img = imread(imagePath);
            imshow(img, 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ENTERPATIENTDATALabel = uilabel(app.UIFigure);
            app.ENTERPATIENTDATALabel.Text = 'ENTER PATIENT DATA';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DiagnoseButton = uibutton(app.UIFigure, 'push');
            app.DiagnoseButton.Text = 'Diagnose';
            app.UploadtheimageButton = uibutton(app.UIFigure, 'push');
            app.UploadtheimageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadtheimageButtonPushed, true);
            app.UploadtheimageButton.Text = 'Upload the image';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Etiqueta.png');
            app.PatientIDEditFieldLabel = uilabel(app.UIFigure);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.UIFigure, 'text');
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'instrucciones2.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PATIENTDATA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end