classdef REGISTER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image3                      matlab.ui.control.Image
        Image5                      matlab.ui.control.Image
        Image4                      matlab.ui.control.Image
        SpecialistIDEditField       matlab.ui.control.EditField
        SpecialistIDEditFieldLabel  matlab.ui.control.Label
        PasswordEditField           matlab.ui.control.EditField
        PasswordEditFieldLabel      matlab.ui.control.Label
        UsernameEditField           matlab.ui.control.EditField
        UsernameEditFieldLabel      matlab.ui.control.Label
        EmailEditField              matlab.ui.control.EditField
        EmailEditFieldLabel         matlab.ui.control.Label
        LastnameEditField           matlab.ui.control.EditField
        LastnameEditFieldLabel      matlab.ui.control.Label
        FirstnameEditField          matlab.ui.control.EditField
        FirstnameEditFieldLabel     matlab.ui.control.Label
        Image6                      matlab.ui.control.Image
        LoginButton                 matlab.ui.control.Button
        RegisterButton              matlab.ui.control.Button
        REGISTERLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            Firstname = string(app.FirstnameEditField.Value);
            Lastname = string(app.LastnameEditField.Value);
            Email = string(app.EmailEditField.Value);
            Username = string(app.UsernameEditField.Value);
            Password = string(app.PasswordEditField.Value);
            SpecialistID = string(app.SpecialistIDEditField.Value);
        
            % Restrictions
            errorMessage = "Registration failed. Please try again.";
        
            if ~isstrprop(char(Firstname(1)), 'upper')
                uialert(app.UIFigure, errorMessage + newline + "First name must start with an uppercase letter.", "Error", "Icon", "warning");
                return
            end
        
            if ~isstrprop(char(Lastname(1)), 'upper')
                uialert(app.UIFigure, errorMessage + newline + "Last name must start with an uppercase letter.", "Error", "Icon", "warning");
                return
            end
        
            allowedDomains = ["@gmail.com", "@mondragon.edu", "@hotmail.com", "@alumni.mondragon.edu"];
            if ~any(contains(Email, allowedDomains))
                uialert(app.UIFigure, errorMessage + newline + "Email must end with: @gmail.com, @mondragon.edu, or @hotmail.com.", "Error", "Icon", "warning");
                return
            end
        
            if strlength(Password) < 4
                uialert(app.UIFigure, errorMessage + newline + "Password must be at least 4 characters long.", "Error", "Icon", "warning");
                return
            end
        
            if strlength(SpecialistID) < 6
                uialert(app.UIFigure, errorMessage + newline + "Specialist ID must be at least 6 characters long.", "Error", "Icon", "warning");
                return
            end
        
            if any(strlength([Firstname, Lastname, Email, Username, Password, SpecialistID]) == 0)
                uialert(app.UIFigure, errorMessage + newline + "All fields are required.", "Error", "Icon", "warning");
                return
            end
        
            T = table(Firstname, Lastname, Email, Username, Password, SpecialistID);
            filename = 'Registration_data.xlsx';
        
            if exist(filename, 'file') == 0
                writetable(T, filename, 'Sheet', 1, 'Range', 'A1');
            else
                data = readtable(filename);
        
                Duplicate = any(strcmp(data.Username, Username) & ...
                                strcmp(data.Password, Password) & ...
                                strcmp(data.SpecialistID, SpecialistID));
        
                if Duplicate
                    uialert(app.UIFigure, ...
                        "This user has already been registered. Please log in.", ...
                        "User Already Registered", "Icon", "warning");
                    return
                end
        
                % If not duplicate, append new row
                writetable(T, filename, 'Sheet', 1, 'WriteMode', 'append');
            end
        
            uialert(app.UIFigure, "Registration successful. Please log in.", "Registration", "Icon", "success");
            pause(1);
            LOGIN
            delete(app)
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            LOGIN
            delete(app)
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            MAINSCREEN
            delete(app)
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            LABEL
            delete(app)
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            INSTRUCTIONS
            delete(app)
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            SETTINGS
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.REGISTERLabel = uilabel(app.UIFigure);
            app.REGISTERLabel.Text = 'REGISTER';
            app.RegisterButton = uibutton(app.UIFigure, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Etiqueta.png');
            app.FirstnameEditFieldLabel = uilabel(app.UIFigure);
            app.FirstnameEditFieldLabel.Text = 'First name';
            app.FirstnameEditField = uieditfield(app.UIFigure, 'text');
            app.LastnameEditFieldLabel = uilabel(app.UIFigure);
            app.LastnameEditFieldLabel.Text = 'Last name';
            app.LastnameEditField = uieditfield(app.UIFigure, 'text');
            app.EmailEditFieldLabel = uilabel(app.UIFigure);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.UIFigure, 'text');
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.SpecialistIDEditFieldLabel = uilabel(app.UIFigure);
            app.SpecialistIDEditFieldLabel.Text = 'Specialist ID';
            app.SpecialistIDEditField = uieditfield(app.UIFigure, 'text');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'instrucciones2.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REGISTER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end