classdef Registrarse2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegistrarseButton               matlab.ui.control.Button
        IdentificadordeespecialistaEditField  matlab.ui.control.EditField
        IdentificadordeespecialistaEditFieldLabel  matlab.ui.control.Label
        ContraseaEditField              matlab.ui.control.EditField
        ContraseaEditFieldLabel         matlab.ui.control.Label
        NombredeusuarioEditField        matlab.ui.control.EditField
        NombredeusuarioEditFieldLabel   matlab.ui.control.Label
        CorreoelectrnicoEditField       matlab.ui.control.EditField
        CorreoelectrnicoEditFieldLabel  matlab.ui.control.Label
        ApellidosEditField              matlab.ui.control.EditField
        ApellidosEditFieldLabel         matlab.ui.control.Label
        NombreEditField                 matlab.ui.control.EditField
        NombreEditFieldLabel            matlab.ui.control.Label
        REGISTRARSELabel                matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegistrarseButton
        function RegistrarseButtonPushed(app, event)
            Nombre = string(app.NombreEditField.Value);
            Apellidos = string(app.ApellidosEditField.Value);
            Correoelectronico = string(app.CorreoelectrnicoEditField.Value);
            Nombredeusuario = string(app.NombredeusuarioEditField.Value);
            Contrasena = string(app.ContraseaEditField.Value);
            Identificadordeespecialista = string(app.IdentificadordeespecialistaEditField.Value);

        %Restricciones
            mensajeError = "Registro incorrecto. Por favor, inténtelo de nuevo.";

            if ~isstrprop(char(Nombre(1)), 'upper')
                uialert(app.UIFigure, mensajeError + newline + "El nombre debe comenzar con mayúscula.", "Error", "Icon", "warning");
                return
            end

            if ~isstrprop(char(Apellidos(1)), 'upper')
                uialert(app.UIFigure, mensajeError + newline + "El apellido debe comenzar con mayúscula.", "Error", "Icon", "warning");
                return
            end

            dominiosPermitidos = ["@gmail.com", "@mondragon.edu", "@hotmail.com", "@alumni.mondragon.edu"];
            if ~any(contains(Correoelectronico, dominiosPermitidos))
                uialert(app.UIFigure, mensajeError + newline + "El correo debe terminar en: @gmail.com, @mondragon.edu o @hotmail.com.", "Error", "Icon", "warning");
                return
            end

            if strlength(Contrasena) < 4
                uialert(app.UIFigure, mensajeError + newline + "La contraseña debe tener al menos 4 caracteres.", "Error", "Icon", "warning");
                return
            end

            if strlength(Identificadordeespecialista) < 6
                uialert(app.UIFigure, mensajeError + newline + "El identificador debe tener al menos 6 caracteres.", "Error", "Icon", "warning");
                return
            end

            if any(strlength([Nombre, Apellidos, Correoelectronico, Nombredeusuario, Contrasena, Identificadordeespecialista]) == 0)
                uialert(app.UIFigure, mensajeError + newline + "Todos los campos son obligatorio.", "Error", "Icon", "warning");
                return
            end

            T = table(Nombre, Apellidos, Correoelectronico, Nombredeusuario, Contrasena, Identificadordeespecialista);
            filename = 'Datos_registro.xlsx';
    
            if exist(filename, 'file') == 0 % Si el archivo no existe, se crea con encabezados
             writetable(T, filename, 'Sheet', 1, 'Range', 'A1');
            else
                datos = readtable(filename);% Si el archivo ya existe, se agrega sin sobrescribir encabezados
                writetable(T, filename, 'Sheet', 1, 'WriteMode', 'append');  % << más limpio y seguro
            end

            uialert(app.UIFigure, "Registro exitoso. Inicie sesión.", "Registro", "Icon", "success");
            pause(1);
            Iniciarsesion  
            delete(app)  
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'instrucciones2.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.REGISTRARSELabel = uilabel(app.UIFigure);
            app.REGISTRARSELabel.Text = 'REGISTRARSE';
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidosEditFieldLabel = uilabel(app.UIFigure);
            app.ApellidosEditFieldLabel.Text = 'Apellidos';
            app.ApellidosEditField = uieditfield(app.UIFigure, 'text');
            app.CorreoelectrnicoEditFieldLabel = uilabel(app.UIFigure);
            app.CorreoelectrnicoEditFieldLabel.Text = 'Correo electrónico';
            app.CorreoelectrnicoEditField = uieditfield(app.UIFigure, 'text');
            app.NombredeusuarioEditFieldLabel = uilabel(app.UIFigure);
            app.NombredeusuarioEditFieldLabel.Text = 'Nombre de usuario';
            app.NombredeusuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.IdentificadordeespecialistaEditFieldLabel = uilabel(app.UIFigure);
            app.IdentificadordeespecialistaEditFieldLabel.Text = 'Identificador de especialista';
            app.IdentificadordeespecialistaEditField = uieditfield(app.UIFigure, 'text');
            app.RegistrarseButton = uibutton(app.UIFigure, 'push');
            app.RegistrarseButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarseButtonPushed, true);
            app.RegistrarseButton.Text = 'Registrarse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Registrarse2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end