classdef Subir < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Label                     matlab.ui.control.Label
        Image8                    matlab.ui.control.Image
        DiagnosticarButton        matlab.ui.control.Button
        Image7                    matlab.ui.control.Image
        oarrastrarunarchivoLabel  matlab.ui.control.Label
        CargarimagenButton        matlab.ui.control.Button
        Image5                    matlab.ui.control.Image
        Image4                    matlab.ui.control.Image
        Image3                    matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
             [archivo, carpeta] = uigetfile({ '*.png;*.jpg;*.jpeg;*.tif;*.tiff', 'Imágenes (*.png,*.jpg,*.jpeg,*.tif)'}, ...
             'Selecciona una imagen');

             if isequal(archivo, 0)
                return
             end

            rutaImagen = fullfile(carpeta, archivo);
            img = imread(rutaImagen);
            imshow(img, 'Parent', app.UIAxes);
        end

        % Button pushed function: DiagnosticarButton
        function DiagnosticarButtonPushed(app, event)
            Diagnostico
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'instrucciones2.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.oarrastrarunarchivoLabel = uilabel(app.UIFigure);
            app.oarrastrarunarchivoLabel.Text = 'o arrastrar un archivo, ';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'LUMA.png');
            app.DiagnosticarButton = uibutton(app.UIFigure, 'push');
            app.DiagnosticarButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticarButtonPushed, true);
            app.DiagnosticarButton.Text = 'Diagnosticar';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'Paciente.png');
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Subir

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end