classdef CAMBIARCONTRASENA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Confirmar                      matlab.ui.control.Label
        Nueva                          matlab.ui.control.Label
        Actual                         matlab.ui.control.Label
        Usuari                         matlab.ui.control.Label
        ConfirmarcontraseaEditField    matlab.ui.control.EditField
        ConfirmarcontraseaEditFieldLabel  matlab.ui.control.Label
        NuevacontraseaEditField        matlab.ui.control.EditField
        NuevacontraseaEditFieldLabel   matlab.ui.control.Label
        ContraseaactualEditField       matlab.ui.control.EditField
        ContraseaactualEditFieldLabel  matlab.ui.control.Label
        NombredeusuarioEditField_2     matlab.ui.control.EditField
        NombredeusuarioEditField_2Label  matlab.ui.control.Label
        CambiarcontraseaButton         matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        CAMBIARCONTRASEALabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Usuari.Tooltip = 'Introduce tu nombre de usuario actual.';
           app.Actual.Tooltip = 'Introduce tu contraseña actual.';
           app.Nueva.Tooltip = 'La nueva contraseña debe tener al menos 4 caracteres.';
           app.Confirmar.Tooltip = 'Debe coincidir con la nueva contraseña.';
        end

        % Button pushed function: CambiarcontraseaButton
        function CambiarcontraseaButtonPushed(app, event)
            Nombredeusuario = app.NombredeusuarioEditField_2.Value;
            contrasenaActual = app.ContraseaactualEditField.Value;
            nuevaContrasena = app.NuevacontraseaEditField.Value;
            confirmarContrasena = app.ConfirmarcontraseaEditField.Value;
        
            if any(strlength([Nombredeusuario, contrasenaActual, nuevaContrasena, confirmarContrasena]) == 0)
                uialert(app.UIFigure, "Todos los campos son obligatorios.", "Error", "Icon", "warning");
                return
            end
        
            if ~strcmp(nuevaContrasena, confirmarContrasena)
                uialert(app.UIFigure, "Las nuevas contraseñas no coinciden.", "Error", "Icon", "warning");
                return
            end
        
            fichero = "Datos_registro.xlsx";
            if ~isfile(fichero)
                uialert(app.UIFigure, "El archivo de registro no existe.", "Error", "Icon", "warning");
                return
            end
        
            data = readtable(fichero, "TextType", "string");
            COLUMNA_USUARIO = "Nombredeusuario";
            COLUMNA_CONTRASENA = "Contrasena";  
        
            idx = find(data.(COLUMNA_USUARIO) == string(Nombredeusuario));
            if isempty(idx)
                uialert(app.UIFigure, "Usuario no encontrado.", "Error", "Icon", "warning");
                return
            end
        
            if data.(COLUMNA_CONTRASENA)(idx) ~= string(contrasenaActual)
                uialert(app.UIFigure, "Contraseña actual incorrecta.", "Error", "Icon", "warning");
                return
            end

            data.(COLUMNA_CONTRASENA)(idx) = string(nuevaContrasena);
            writetable(data, fichero, "WriteMode", "overwritesheet");

            uialert(app.UIFigure, "Contraseña cambiada exitosamente.", "Éxito", "Icon", "success");
            pause(1);
            LUMADIAGNOSTICS  
            delete(app)
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            AJUSTES
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CAMBIARCONTRASEALabel = uilabel(app.UIFigure);
            app.CAMBIARCONTRASEALabel.Text = 'CAMBIAR CONTRASEÑA';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.CambiarcontraseaButton = uibutton(app.UIFigure, 'push');
            app.CambiarcontraseaButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarcontraseaButtonPushed, true);
            app.CambiarcontraseaButton.Text = 'Cambiar contraseña';
            app.NombredeusuarioEditField_2Label = uilabel(app.UIFigure);
            app.NombredeusuarioEditField_2Label.Text = 'Nombre de usuario';
            app.NombredeusuarioEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ContraseaactualEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaactualEditFieldLabel.Text = 'Contraseña actual';
            app.ContraseaactualEditField = uieditfield(app.UIFigure, 'text');
            app.NuevacontraseaEditFieldLabel = uilabel(app.UIFigure);
            app.NuevacontraseaEditFieldLabel.Text = 'Nueva contraseña';
            app.NuevacontraseaEditField = uieditfield(app.UIFigure, 'text');
            app.ConfirmarcontraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ConfirmarcontraseaEditFieldLabel.Text = 'Confirmar contraseña';
            app.ConfirmarcontraseaEditField = uieditfield(app.UIFigure, 'text');
            app.Usuari = uilabel(app.UIFigure);
            app.Usuari.Text = '*';
            app.Actual = uilabel(app.UIFigure);
            app.Actual.Text = '*';
            app.Nueva = uilabel(app.UIFigure);
            app.Nueva.Text = '*';
            app.Confirmar = uilabel(app.UIFigure);
            app.Confirmar.Text = '*';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CAMBIARCONTRASENA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end