classdef CHANGEPASSWORD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Match                          matlab.ui.control.Label
        New                            matlab.ui.control.Label
        Current                        matlab.ui.control.Label
        User                           matlab.ui.control.Label
        ConfirmpasswordEditField       matlab.ui.control.EditField
        ConfirmpasswordEditFieldLabel  matlab.ui.control.Label
        NewpasswordEditField           matlab.ui.control.EditField
        NewpasswordEditFieldLabel      matlab.ui.control.Label
        CurrentpasswordEditField       matlab.ui.control.EditField
        CurrentpasswordEditFieldLabel  matlab.ui.control.Label
        UsernameEditField              matlab.ui.control.EditField
        UsernameEditFieldLabel         matlab.ui.control.Label
        ChangepasswordButton           matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        CHANGEPASSWORDLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.User.Tooltip = 'Enter your current username.';
            app.Current.Tooltip = 'Enter your current password.';
            app.New.Tooltip = 'The new password must be at least 4 characters long.';
            app.Match.Tooltip = 'It must match the new password.';
        end

        % Button pushed function: ChangepasswordButton
        function ChangepasswordButtonPushed(app, event)
            Username = app.UsernameEditField.Value;
            currentPassword = app.CurrentpasswordEditField.Value;
            newPassword = app.NewpasswordEditField.Value;
            confirmPassword = app.ConfirmpasswordEditField.Value;

            if any(strlength([Username, currentPassword, newPassword, confirmPassword]) == 0)
                uialert(app.UIFigure, "All fields are required.", "Error", "Icon", "warning");
                return
            end
            
            if ~strcmp(newPassword, confirmPassword)
                uialert(app.UIFigure, "The new passwords do not match.", "Error", "Icon", "warning");
                return
            end
            
            file = "Registration_data.xlsx";
            if ~isfile(file)
                uialert(app.UIFigure, "The registration data file does not exist.", "Error", "Icon", "warning");
                return
            end
            
            data = readtable(file, "TextType", "string");
            COLUMN_USERNAME = "Username";
            COLUMN_PASSWORD = "Password";  
            
            idx = find(data.(COLUMN_USERNAME) == string(Username));
            if isempty(idx)
                uialert(app.UIFigure, "User not found.", "Error", "Icon", "warning");
                return
            end
            
            if data.(COLUMN_PASSWORD)(idx) ~= string(currentPassword)
                uialert(app.UIFigure, "Current password is incorrect.", "Error", "Icon", "warning");
                return
            end
            
            data.(COLUMN_PASSWORD)(idx) = string(newPassword);
            writetable(data, file, "WriteMode", "overwritesheet");
            
            uialert(app.UIFigure, "Password changed successfully.", "Success", "Icon", "success");
            pause(1);
            MAINSCREEN
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CHANGEPASSWORDLabel = uilabel(app.UIFigure);
            app.CHANGEPASSWORDLabel.Text = 'CHANGE PASSWORD';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.ChangepasswordButton = uibutton(app.UIFigure, 'push');
            app.ChangepasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ChangepasswordButtonPushed, true);
            app.ChangepasswordButton.Text = 'Change password';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentpasswordEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentpasswordEditFieldLabel.Text = 'Current password';
            app.CurrentpasswordEditField = uieditfield(app.UIFigure, 'text');
            app.NewpasswordEditFieldLabel = uilabel(app.UIFigure);
            app.NewpasswordEditFieldLabel.Text = 'New password';
            app.NewpasswordEditField = uieditfield(app.UIFigure, 'text');
            app.ConfirmpasswordEditFieldLabel = uilabel(app.UIFigure);
            app.ConfirmpasswordEditFieldLabel.Text = 'Confirm password';
            app.ConfirmpasswordEditField = uieditfield(app.UIFigure, 'text');
            app.User = uilabel(app.UIFigure);
            app.User.Text = '*';
            app.Current = uilabel(app.UIFigure);
            app.Current.Text = '*';
            app.New = uilabel(app.UIFigure);
            app.New.Text = '*';
            app.Match = uilabel(app.UIFigure);
            app.Match.Text = '*';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CHANGEPASSWORD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end