classdef DATOSPACIENTE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image6                        matlab.ui.control.Image
        CargarimagenButton            matlab.ui.control.Button
        DiagnosticarButton            matlab.ui.control.Button
        GuardarButton                 matlab.ui.control.Button
        FechaDatePicker               matlab.ui.control.DatePicker
        FechaDatePickerLabel          matlab.ui.control.Label
        IDdelpacienteEditField        matlab.ui.control.EditField
        IDdelpacienteEditFieldLabel   matlab.ui.control.Label
        INGRESEDATOSDELPACIENTELabel  matlab.ui.control.Label
        Image5                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
             [archivo, carpeta] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.tiff', 'Imágenes (.png,.jpg,.jpeg,*.tif)'}, ...
             'Selecciona una imagen');

             if isequal(archivo, 0)
                return
             end

            rutaImagen = fullfile(carpeta, archivo);
            img = imread(rutaImagen);
            imshow(img, 'Parent',app.UIAxes);
        end

        % Button pushed function: DiagnosticarButton
        function DiagnosticarButtonPushed(app, event)
            DIAGNOSTICO
            delete(app)
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            IDdelpaciente = string(app.IDdelpacienteEditField.Value);
            Fecha = string(app.FechaDatePicker.Value)
            
            T = table(IDdelpaciente, Fecha);
            filename = 'Datos_paciente.xlsx';
    
            if exist(filename,'file') == 0
              writetable(T, filename, 'Sheet', 1, 'Range', 'A1');
            
            else
            datos = readtable(filename);

            Duplicado = any( strcmp(datos.IDdelpaciente, IDdelpaciente) & strcmp(datos.Fecha, Fecha))
            
            if Duplicado
                uialert(app.UIFigure, ...
                        "Por favor, esto paciente ya ha sido registrado en esta fecha. Intentalo de nuevo.", ...
                        "Paciente ya registrado", "Icon", "warning");
                    return
            end
                writetable(T, filename, 'Sheet', 1, 'WriteMode', 'append');
            end

            uialert(app.UIFigure, "Paciente registrado correctamente. Porfavor, carga la imagen.", "Registro", "Icon", "success");
            pause(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'instrucciones2.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.INGRESEDATOSDELPACIENTELabel = uilabel(app.UIFigure);
            app.INGRESEDATOSDELPACIENTELabel.Text = 'INGRESE DATOS DEL PACIENTE';
            app.IDdelpacienteEditFieldLabel = uilabel(app.UIFigure);
            app.IDdelpacienteEditFieldLabel.Text = 'ID del paciente';
            app.IDdelpacienteEditField = uieditfield(app.UIFigure, 'text');
            app.FechaDatePickerLabel = uilabel(app.UIFigure);
            app.FechaDatePickerLabel.Text = 'Fecha';
            app.FechaDatePicker = uidatepicker(app.UIFigure);
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.DiagnosticarButton = uibutton(app.UIFigure, 'push');
            app.DiagnosticarButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticarButtonPushed, true);
            app.DiagnosticarButton.Text = 'Diagnosticar';
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Etiqueta.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DATOSPACIENTE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end