classdef INICIARSESION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image3                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        IdentificadordeespecialistaEditField  matlab.ui.control.EditField
        IdentificadordeespecialistaEditFieldLabel  matlab.ui.control.Label
        ContraseaEditField             matlab.ui.control.EditField
        ContraseaEditFieldLabel        matlab.ui.control.Label
        NombredeusuarioEditField       matlab.ui.control.EditField
        NombredeusuarioEditFieldLabel  matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        IniciarsesinButton             matlab.ui.control.Button
        Image4                         matlab.ui.control.Image
        INICIARSESINLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarsesinButton
        function IniciarsesinButtonPushed(app, event)
            Nombredeusuario=string(app.NombredeusuarioEditField.Value);
            Contrasena=string(app.ContraseaEditField.Value);
            Identificadordeespecialista=string(app.IdentificadordeespecialistaEditField.Value);

           filename = 'Datos_registro.xlsx';
           T= readtable(filename,'Sheet',1,'Range','D:F');
           
           length=size(T);
           k = 1;

            for i = 1:length
                if strcmp(Nombredeusuario, T.Nombredeusuario{i}) && strcmp(Contrasena, T.Contrasena{i}) && strcmp(Identificadordeespecialista, T.Identificadordeespecialista{i})
       
                    k = 2;
                    break;
                end
            end

            if k == 2
              uialert(app.UIFigure, "Inicio de sesión exitoso", "Éxito", "Icon", "success");
              pause(1);
              DATOSPACIENTE
              delete(app);
            else
                uialert(app.UIFigure, "Credenciales incorrectas", "Error", "Icon", "warning");
            end
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            ETIQUETA
            delete(app)
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            INSTRUCCIONES
            delete(app)
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            AJUSTES
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.INICIARSESINLabel = uilabel(app.UIFigure);
            app.INICIARSESINLabel.Text = 'INICIAR SESIÓN';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'instrucciones2.png');
            app.IniciarsesinButton = uibutton(app.UIFigure, 'push');
            app.IniciarsesinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarsesinButtonPushed, true);
            app.IniciarsesinButton.Text = 'Iniciar sesión';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Etiqueta.png');
            app.NombredeusuarioEditFieldLabel = uilabel(app.UIFigure);
            app.NombredeusuarioEditFieldLabel.Text = 'Nombre de usuario';
            app.NombredeusuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.IdentificadordeespecialistaEditFieldLabel = uilabel(app.UIFigure);
            app.IdentificadordeespecialistaEditFieldLabel.Text = 'Identificador de especialista';
            app.IdentificadordeespecialistaEditField = uieditfield(app.UIFigure, 'text');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INICIARSESION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end