classdef PACIENTE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        RegistrarpacienteTab          matlab.ui.container.Tab
        Fech                          matlab.ui.control.Label
        Paciente                      matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Image5                        matlab.ui.control.Image
        GuardarButton                 matlab.ui.control.Button
        FechadediagnsticoDatePicker   matlab.ui.control.DatePicker
        FechadediagnsticoDatePickerLabel  matlab.ui.control.Label
        IDdelpacienteEditField        matlab.ui.control.EditField
        IDdelpacienteEditFieldLabel   matlab.ui.control.Label
        INGRESEDATOSDELPACIENTELabel  matlab.ui.control.Label
        DiagnosticarTab               matlab.ui.container.Tab
        LamparaDensidad               matlab.ui.control.Lamp
        Image5_2                      matlab.ui.control.Image
        Image3_2                      matlab.ui.control.Image
        Label4                        matlab.ui.control.Label
        IDdelpacienteLabel            matlab.ui.control.Label
        DiagnosticarButton_2          matlab.ui.control.Button
        Densidad                      matlab.ui.control.Label
        Tipo                          matlab.ui.control.Label
        Diagnostico                   matlab.ui.control.Label
        DensidadparasitariaLabel      matlab.ui.control.Label
        TipodemalariaLabel            matlab.ui.control.Label
        DiagnsticodelamalariaLabel    matlab.ui.control.Label
        RESULTADOSLabel               matlab.ui.control.Label
        DIAGNSTICOLabel               matlab.ui.control.Label
        DiagnosticarButton            matlab.ui.control.Button
        CargarimagenButton_2          matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        HistorialTab                  matlab.ui.container.Tab
        Image9                        matlab.ui.control.Image
        Image8                        matlab.ui.control.Image
        Image7                        matlab.ui.control.Image
        Image6                        matlab.ui.control.Image
        Image5_3                      matlab.ui.control.Image
        Image3_3                      matlab.ui.control.Image
        RecargarhistorialButton       matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        IDdelpaciente
        img 
        DatosHistorial
        rutaImagen
        Fecha
    end
    
    methods (Access = private)

        function resetRegistro(app)
        app.IDdelpacienteEditField.Value = '';
        app.FechadediagnsticoDatePicker.Value = datetime('');
        app.IDdelpaciente = '';
        end

        function resetDiagnostico(app)
            app.Diagnostico.Text = '';
            app.Tipo.Text = '';
            app.Densidad.Text = '';
            app.Label4.Text = '';
        
            app.Diagnostico.Visible = 'off';
            app.Tipo.Visible = 'off';
            app.Densidad.Visible = 'off';
            app.LamparaDensidad.Visible = 'off';

            cla(app.UIAxes);
        
            app.img = [];
            app.rutaImagen = '';
         end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.Diagnostico.Visible = 'off';
          app.Tipo.Visible = 'off';
          app.Densidad.Visible = 'off';
          app.LamparaDensidad.Visible = 'off';
          app.Paciente.Tooltip = 'El identificador del paciente debe comenzar con "PA" seguido de 4 números.';
          app.Fech.Tooltip = 'Introduce la fecha en la que se realizará el diagnóstico.';
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            app.IDdelpaciente = upper(strtrim(string(app.IDdelpacienteEditField.Value)));
            app.Fecha = app.FechadediagnsticoDatePicker.Value;
            disp(app.IDdelpaciente)
            % Validaciones de campos vacíos
            if app.IDdelpaciente == "" || isnat(app.Fecha)
                uialert(app.UIFigure, ...
                    "Por favor, rellena tanto el ID del paciente como la fecha de diagnóstico.", ...
                    "Campos incompletos", "Icon", "warning");
                return;
            end
        
            % Validar formato del ID
            if isempty(regexp(app.IDdelpaciente, "^PA\d{4}$", "once"))
                uialert(app.UIFigure, ...
                    "El ID del paciente debe comenzar con 'PA' seguido de 4 números. Ejemplo: PA1234", ...
                    "Formato de ID inválido", "Icon", "warning");
                return;
            end
            disp(app.IDdelpaciente)
            % Crear fila con columnas correctas
            nuevaFila = table(app.IDdelpaciente, app.Fecha, "", "", ...
                'VariableNames', {'IDdelpaciente', 'Fecha', 'EsPrimerRegistro', 'Resultados'});
        
            filename = 'Datos_paciente.xlsx';
        
            if exist(filename, 'file') == 0
                % Crear nuevo archivo
                writetable(nuevaFila, filename);
            else
                % Leer archivo existente
                datos = readtable(filename);
        
                % Si faltan columnas, agregarlas
                columnas = {'IDdelpaciente', 'Fecha', 'EsPrimerRegistro', 'Resultados'};
                for i = 1:length(columnas)
                    if ~ismember(columnas{i}, datos.Properties.VariableNames)
                        datos.(columnas{i}) = repmat("", height(datos), 1);
                    end
                end
        
                % Verificar si ya existe el registro
                existe = strcmp(datos.IDdelpaciente, app.IDdelpaciente) & datos.Fecha == app.Fecha;
                if any(existe)
                    uialert(app.UIFigure, ...
                        "Este paciente ya ha sido registrado en esta fecha. Inténtalo de nuevo.", ...
                        "Paciente ya registrado", "Icon", "warning");
                    return;
                end
        
                % Agregar el nuevo registro
                datosActualizados = [datos; nuevaFila];
                writetable(datosActualizados, filename);
            end
        
            % Confirmación
            app.Label4.Text = app.IDdelpaciente;
            uialert(app.UIFigure, ...
                "Paciente registrado correctamente. Ahora puedes continuar con el diagnóstico.", ...
                "Registro exitoso", "Icon", "success");
        end

        % Button pushed function: RecargarhistorialButton
        function RecargarhistorialButtonPushed(app, event)
            datos = readtable('Datos_paciente.xlsx');
        
            id_correctos = ~cellfun(@isempty, regexp(datos.IDdelpaciente, "^PA\d{4}$", "once"));
            fecha_correcta = ~isnat(datetime(datos.Fecha, 'InputFormat', 'dd-MMM-yyyy', 'Format', 'dd-MMM-yyyy'));
            

            datos_validos = datos(id_correctos & fecha_correcta, :);
            datos_validos = sortrows(datos_validos, {'IDdelpaciente', 'Fecha'});
        
            es_primer = strings(height(datos_validos), 1);
            [~, first_idx] = unique(datos_validos.IDdelpaciente, 'first');
            es_primer(first_idx) = "👤 Primer registro";
            datos_validos.EsPrimerRegistro = es_primer;
        
            columnas_orden = {'IDdelpaciente', 'Fecha', 'EsPrimerRegistro', 'Resultados'};
            columnas_presentes = intersect(columnas_orden, datos_validos.Properties.VariableNames, 'stable');
            datos_mostrar = datos_validos(:, columnas_presentes);
        
            app.DatosHistorial = datos_mostrar;
            app.UITable.Data = datos_mostrar;           
        end

        % Button pushed function: CargarimagenButton_2
        function CargarimagenButton_2Pushed(app, event)
             [archivo, carpeta] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.tiff', 'Imágenes (.png,.jpg,.jpeg,*.tif)'}, ...
             'Selecciona una imagen');

             if isequal(archivo, 0)
                return
             end

            app.rutaImagen = fullfile(carpeta, archivo);
            app.img = imread(app.rutaImagen);
            imshow(app.img, 'Parent',app.UIAxes);
        end

        % Button pushed function: DiagnosticarButton_2
        function DiagnosticarButton_2Pushed(app, event)
            if isempty(app.img)
               uialert(app.UIFigure, 'Primero cargue una imagen', 'Aviso');
               return;
            end 
            [diagnostico, tipo, densidad, imagen_segmentada] = diagnostico_malaria(app.rutaImagen);


            imshow(imagen_segmentada, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Segmentación con parásitos detectados');
            axis(app.UIAxes2, 'image');  % Mantener proporciones correctas
        
            app.Diagnostico.Text = char(diagnostico);
            app.Tipo.Text = char(tipo);
            if ischar(densidad)
                app.Densidad.Text = densidad;
            else
                app.Densidad.Text = sprintf('%.2f parásitos/µL', densidad);
            end

            app.Diagnostico.Visible = 'on';
            app.Tipo.Visible = 'on';
            app.Densidad.Visible = 'on';
            app.LamparaDensidad.Visible = 'on';

            if strcmp(densidad, "sano")
                app.LamparaDensidad.Color = [0.5 0.5 0.5]; % Gris 
            elseif strcmp(densidad, "Baja")
                app.LamparaDensidad.Color = [0 1 0];       % Verde
            elseif strcmp(densidad, "Moderada")
                app.LamparaDensidad.Color = [1 1 0];       % Amarillo
            else
                app.LamparaDensidad.Color = [1 0 0];       % Rojo
            end

           filename = 'Datos_paciente.xlsx';
           idPaciente = app.Label4.Text;
           resultado = string(tipo);
            
           if isfile(filename)
                datos = readtable(filename, 'TextType', 'string'); 
                   if ~isstring(datos.Resultados)
                        datos.Resultados = string(datos.Resultados);
                   end
                   disp(resultado)
                fila = find(strcmp(datos.IDdelpaciente, idPaciente) & datos.Fecha == app.Fecha);
                datos.Resultados(fila) = resultado;
                writetable(datos, filename);
           end
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            AJUSTES
            delete(app)
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            datos_ordenados = sortrows(app.DatosHistorial, 'IDdelpaciente');
            app.UITable.Data = datos_ordenados;
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            datos_ordenados = sortrows(app.DatosHistorial, 'Fecha', 'ascend');
            app.UITable.Data = datos_ordenados;
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
           datos_ordenados = sortrows(app.DatosHistorial, 'Fecha', 'descend');
           app.UITable.Data = datos_ordenados;
        end

        % Image clicked function: Image9
        function Image9Clicked(app, event)
           datos_ordenados = sortrows(app.DatosHistorial, 'IDdelpaciente', 'descend');
           app.UITable.Data = datos_ordenados;
        end

        % Image clicked function: Image3_3
        function Image3_3Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Image clicked function: Image5_3
        function Image5_3Clicked(app, event)
            AJUSTES
            delete(app)
        end

        % Image clicked function: Image3_2
        function Image3_2Clicked(app, event)
            LUMADIAGNOSTICS
            delete(app)
        end

        % Image clicked function: Image5_2
        function Image5_2Clicked(app, event)
            AJUSTES
            delete(app)
        end

        % Callback function
        function Image10Clicked(app, event)
           datos_ordenados = sortrows(app.DatosHistorial, '👤 Primer registro', 'ascend');
           app.UITable.Data = datos_ordenados;
        end

        % Callback function
        function Image11Clicked(app, event)
           datos_ordenados = sortrows(app.DatosHistorial, '👤 Primer registro', 'descend');
           app.UITable.Data = datos_ordenados;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = event.NewValue;

            if selectedTab ~= app.RegistrarpacienteTab
                resetRegistro(app);
            end

            if selectedTab ~= app.DiagnosticarTab;
                resetDiagnostico(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RegistrarpacienteTab = uitab(app.TabGroup);
            app.RegistrarpacienteTab.Title = 'Registrar paciente';
            app.INGRESEDATOSDELPACIENTELabel = uilabel(app.RegistrarpacienteTab);
            app.INGRESEDATOSDELPACIENTELabel.Text = 'INGRESE DATOS DEL PACIENTE';
            app.IDdelpacienteEditFieldLabel = uilabel(app.RegistrarpacienteTab);
            app.IDdelpacienteEditFieldLabel.Text = 'ID del paciente';
            app.IDdelpacienteEditField = uieditfield(app.RegistrarpacienteTab, 'text');
            app.FechadediagnsticoDatePickerLabel = uilabel(app.RegistrarpacienteTab);
            app.FechadediagnsticoDatePickerLabel.Text = 'Fecha de diagnóstico';
            app.FechadediagnsticoDatePicker = uidatepicker(app.RegistrarpacienteTab);
            app.GuardarButton = uibutton(app.RegistrarpacienteTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Image5 = uiimage(app.RegistrarpacienteTab);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image3 = uiimage(app.RegistrarpacienteTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.Paciente = uilabel(app.RegistrarpacienteTab);
            app.Paciente.Text = '*';
            app.Fech = uilabel(app.RegistrarpacienteTab);
            app.Fech.Text = '*';
            app.DiagnosticarTab = uitab(app.TabGroup);
            app.DiagnosticarTab.Title = 'Diagnosticar';
            app.UIAxes = uiaxes(app.DiagnosticarTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.DiagnosticarTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.CargarimagenButton_2 = uibutton(app.DiagnosticarTab, 'push');
            app.CargarimagenButton_2.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButton_2Pushed, true);
            app.CargarimagenButton_2.Text = 'Cargar imagen';
            app.DiagnosticarButton = uibutton(app.DiagnosticarTab, 'push');
            app.DiagnosticarButton.Text = 'Diagnosticar';
            app.DIAGNSTICOLabel = uilabel(app.DiagnosticarTab);
            app.DIAGNSTICOLabel.Text = 'DIAGNÓSTICO';
            app.RESULTADOSLabel = uilabel(app.DiagnosticarTab);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.DiagnsticodelamalariaLabel = uilabel(app.DiagnosticarTab);
            app.DiagnsticodelamalariaLabel.Text = 'Diagnóstico de la malaria:';
            app.TipodemalariaLabel = uilabel(app.DiagnosticarTab);
            app.TipodemalariaLabel.Text = 'Tipo de malaria: ';
            app.DensidadparasitariaLabel = uilabel(app.DiagnosticarTab);
            app.DensidadparasitariaLabel.Text = 'Densidad parasitaria:';
            app.Diagnostico = uilabel(app.DiagnosticarTab);
            app.Tipo = uilabel(app.DiagnosticarTab);
            app.Tipo.Text = 'Label2';
            app.Densidad = uilabel(app.DiagnosticarTab);
            app.Densidad.Text = 'Label3';
            app.DiagnosticarButton_2 = uibutton(app.DiagnosticarTab, 'push');
            app.DiagnosticarButton_2.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticarButton_2Pushed, true);
            app.DiagnosticarButton_2.Text = 'Diagnosticar';
            app.IDdelpacienteLabel = uilabel(app.DiagnosticarTab);
            app.IDdelpacienteLabel.Text = 'ID del paciente: ';
            app.Label4 = uilabel(app.DiagnosticarTab);
            app.Label4.Text = 'Label4';
            app.Image3_2 = uiimage(app.DiagnosticarTab);
            app.Image3_2.ImageClickedFcn = createCallbackFcn(app, @Image3_2Clicked, true);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.Image5_2 = uiimage(app.DiagnosticarTab);
            app.Image5_2.ImageClickedFcn = createCallbackFcn(app, @Image5_2Clicked, true);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.LamparaDensidad = uilamp(app.DiagnosticarTab);
            app.HistorialTab = uitab(app.TabGroup);
            app.HistorialTab.Title = 'Historial';
            app.UITable = uitable(app.HistorialTab);
            app.UITable.ColumnName = {'ID del paciente'; 'Fecha de diagnóstico'; 'Primer registro'; 'Resultados'};
            app.UITable.RowName = {};
            app.RecargarhistorialButton = uibutton(app.HistorialTab, 'push');
            app.RecargarhistorialButton.ButtonPushedFcn = createCallbackFcn(app, @RecargarhistorialButtonPushed, true);
            app.RecargarhistorialButton.Text = 'Recargar historial';
            app.Image3_3 = uiimage(app.HistorialTab);
            app.Image3_3.ImageClickedFcn = createCallbackFcn(app, @Image3_3Clicked, true);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'hig.ia.png');
            app.Image5_3 = uiimage(app.HistorialTab);
            app.Image5_3.ImageClickedFcn = createCallbackFcn(app, @Image5_3Clicked, true);
            app.Image5_3.ImageSource = fullfile(pathToMLAPP, 'ajustes2.png');
            app.Image6 = uiimage(app.HistorialTab);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'arriba.png');
            app.Image7 = uiimage(app.HistorialTab);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'arriba.png');
            app.Image8 = uiimage(app.HistorialTab);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'abajo.png');
            app.Image9 = uiimage(app.HistorialTab);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Image9Clicked, true);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'abajo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PACIENTE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end